classdef VollImBilde < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TiltPosSlider                matlab.ui.control.Slider
        TiltPosSliderLabel           matlab.ui.control.Label
        PanPosSlider                 matlab.ui.control.Slider
        PanPosSliderLabel            matlab.ui.control.Label
        StateBildverarbeitung        matlab.ui.control.Lamp
        BildverarbeitungOnOffButton  matlab.ui.control.StateButton
        ControllerState              matlab.ui.control.Lamp
        WebcamwhlenDropDown          matlab.ui.control.DropDown
        WebcamwhlenDropDownLabel     matlab.ui.control.Label
        mitte                        matlab.ui.control.Button
        ControllerButton             matlab.ui.control.StateButton
        h1VollimBildeV01h1Label      matlab.ui.control.Label
        untenrechts                  matlab.ui.control.Button
        untenlinks                   matlab.ui.control.Button
        obenrechts                   matlab.ui.control.Button
        obenlinks                    matlab.ui.control.Button
        canvas                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Cam % WebCamobjekt
        Camlist
        Ardu %Arduinoobjekt
        Pan %Servoobjekt für Panbewegung
        Tilt %Servoobjekt für Tiltbewegung
        PanStart = 0.5;
        TiltStart = 1;
        Detector = ImagePeopleDetector('peopleACF', 'hold'); %Bilderkennung
        SampleTime = 0.15;
        PanController = Controller('PI', 1.89, 0.091, app.SampleTime, app.PanStart); %Reglerobjekt
        TiltController = Controller('PI', 2.4, 0.07, app.SamlpeTime, app.TiltStart);
        Mounting % Davon hängen auch Buttons(Sollwerte) ab!
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Arduino initialisieren
            app.Ardu = arduino('COM6', 'Nano33IoT', 'Libraries', 'Servo'); %evtl auch noch als Dropdown?
            app.Pan = servo(app.Ardu, 'D2');
            app.Pan.writePosition(app.PanStart);
            app.Tilt = servo(app.Ardu, 'D3');
            app.Tilt.writePosition(app.TiltStart);
            %Dropdown für WebCam initalisieren
            res = imaqhwinfo('winvideo');
            app.Camlist = {res.DeviceInfo.DeviceName};
            app.WebcamwhlenDropDown.Items = app.Camlist;
            app.WebcamwhlenDropDown.Value = app.Camlist(1);
            %Webcam initalisieren
            app.Cam = videoinput('winvideo', 1);
            triggerconfig(app.Cam, 'manual');
            app.Cam.start; %ToDo: app.Cam.stop am Ende
            %Bilderkennung initalisieren
            app.Detector = ImagePeopleDetector(app.VisionType, 'hold');
            %Silder initalisieren
            app.PanPosSlider = app.PanStart;
            app.TiltPosSlider = app.TiltStart;
        end

        % Value changed function: WebcamwhlenDropDown
        function WebcamwhlenDropDownValueChanged(app, event)
            app.Cam.stop;
            value = app.WebcamwhlenDropDown.Value;
            webcamNum = find(strcmp(app.Camlist, value));
            app.Cam = videoinput('winvideo', webcamNum);
            triggerconfig(app.Cam, 'manual');
            app.Cam.start; %ToDo: app.Cam.stop am Ende?
        end

        % Drop down opening function: WebcamwhlenDropDown
        function WebcamwhlenDropDownOpening(app, event)
            res = imaqhwinfo('winvideo');
            app.Camlist = {res.DeviceInfo.DeviceName};
            app.WebcamwhlenDropDown.Items = app.Camlist;
        end

        % Value changed function: ControllerButton
        function ControllerButtonValueChanged(app, event)
            value = app.ControllerButton.Value;
            if value
                app.ControllerState.Color = [0.00 1.00 0.00]; %Grün
                %evtl noch Text ändern auf Button
            else
                app.ControllerButton.Color = [1.00 0.00 0.00]; %Rot
            end
        end

        % Value changed function: BildverarbeitungOnOffButton
        function BildverarbeitungOnOffButtonValueChanged(app, event)
            value = app.BildverarbeitungOnOffButton.Value;
            if value
                app.StateBildverarbeitung.Color = [0.00 1.00 0.00]; %Grün
                %evtl noch Text ändern auf Button
            else
                app.StateBildverarbeitung.Color = [1.00 0.00 0.00]; %Rot
            end
            while(app.BildverarbeitungOnOffButton.Value) 
                tic;
                act_img = getsnapshot(app.Cam);
                %Bildverarbeitung
                [out_img,is_detected,x_Filt,y_Filt] = app.Detector.doPeopleDetection(act_img);
                %Controller Kontrollstruktur über ControllerOnOffButton
                if app.ControllerButton.Value
                    %Reglerobjekt Werte übergeben
                    uPan = app.PanController.getOutput(x_Filt);
                    uTilt = app.PanController.getOutput(y_Filt);
                    %Servos schreiben
                    app.Pan.writePosition(uPan);
                    app.Tilt.writePosition(uTilt);
                    % Kann zur Laufzeitoptimierung raus
                    app.PanPosSlider.Value = uPan;
                    app.TiltPosSlider.Value = uTilt;
                else
                    %Werte aus Schiebereglern schreiben
                    app.Pan.writePosition(app.PanPosSlider.Value);
                    app.Tilt.writePosition(app.TiltPosSlider.Value);
                end
                %Bild akutallisieren
                imshow(out_img, 'Parent', app.canvas); 
                %Pause um interrupts zu bearbeiten und feste Abtaktrate zu
                %geben
                %evtl überwachen und flexibel machen, dass genug Zeit für
                %interrupts ist
                looptime = toc;
                if looptime < app.SampleTime
                    pause(app.SampleTime-looptime);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.canvas = uiaxes(app.UIFigure);
            title(app.canvas, 'Title')
            xlabel(app.canvas, 'X')
            ylabel(app.canvas, 'Y')
            zlabel(app.canvas, 'Z')
            app.obenlinks = uibutton(app.UIFigure, 'push');
            app.obenlinks.Text = 'oben links';
            app.obenrechts = uibutton(app.UIFigure, 'push');
            app.obenrechts.Text = 'oben rechts';
            app.untenlinks = uibutton(app.UIFigure, 'push');
            app.untenlinks.Text = 'unten links';
            app.untenrechts = uibutton(app.UIFigure, 'push');
            app.untenrechts.Text = 'unten rechts';
            app.h1VollimBildeV01h1Label = uilabel(app.UIFigure);
            app.h1VollimBildeV01h1Label.Interpreter = 'html';
            app.h1VollimBildeV01h1Label.Text = '<h1>Voll im Bilde V0.1.<h1>';
            app.ControllerButton = uibutton(app.UIFigure, 'state');
            app.ControllerButton.ValueChangedFcn = createCallbackFcn(app, @ControllerButtonValueChanged, true);
            app.ControllerButton.Text = 'Folgen';
            app.mitte = uibutton(app.UIFigure, 'push');
            app.mitte.Text = 'mitte';
            app.WebcamwhlenDropDownLabel = uilabel(app.UIFigure);
            app.WebcamwhlenDropDownLabel.Text = 'Webcam wählen ..';
            app.WebcamwhlenDropDown = uidropdown(app.UIFigure);
            app.WebcamwhlenDropDown.Items = {'...'};
            app.WebcamwhlenDropDown.DropDownOpeningFcn = createCallbackFcn(app, @WebcamwhlenDropDownOpening, true);
            app.WebcamwhlenDropDown.ValueChangedFcn = createCallbackFcn(app, @WebcamwhlenDropDownValueChanged, true);
            app.WebcamwhlenDropDown.Value = '...';
            app.ControllerState = uilamp(app.UIFigure);
            app.BildverarbeitungOnOffButton = uibutton(app.UIFigure, 'state');
            app.BildverarbeitungOnOffButton.ValueChangedFcn = createCallbackFcn(app, @BildverarbeitungOnOffButtonValueChanged, true);
            app.BildverarbeitungOnOffButton.Text = {'Bildverarbeitung'; 'On/Off'};
            app.StateBildverarbeitung = uilamp(app.UIFigure);
            app.PanPosSliderLabel = uilabel(app.UIFigure);
            app.PanPosSliderLabel.Text = 'Pan Pos.';
            app.PanPosSlider = uislider(app.UIFigure);
            app.PanPosSlider.Limits = [0 1];
            app.TiltPosSliderLabel = uilabel(app.UIFigure);
            app.TiltPosSliderLabel.Text = 'Tilt Pos.';
            app.TiltPosSlider = uislider(app.UIFigure);
            app.TiltPosSlider.Limits = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VollImBilde

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end